classdef BME301_Nernst < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        K_inmMEditFieldLabel           matlab.ui.control.Label
        K_inmMEditField                matlab.ui.control.NumericEditField
        K_inmMSliderLabel              matlab.ui.control.Label
        K_inmMSlider                   matlab.ui.control.Slider
        K_outmMSliderLabel             matlab.ui.control.Label
        K_outmMSlider                  matlab.ui.control.Slider
        K_outmMEditFieldLabel          matlab.ui.control.Label
        K_outmMEditField               matlab.ui.control.NumericEditField
        TemperatureCSliderLabel        matlab.ui.control.Label
        TemperatureCSlider             matlab.ui.control.Slider
        TemperatureCEditFieldLabel     matlab.ui.control.Label
        TemperatureCEditField          matlab.ui.control.NumericEditField
        MembranePotentialmVGaugeLabel  matlab.ui.control.Label
        MembranePotentialmVGauge       matlab.ui.control.LinearGauge
        MembranePotentialmVEditFieldLabel  matlab.ui.control.Label
        MembranePotentialmVEditField   matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        R = 8.314 % Gas constant (J/mol*K)
        F = 96485.3321 % Faraday's constant (C/mol)
        z = +1 % charge of potassium
    end
    
    methods (Access = private)
        
        function Vm_calculator(app)
            K_in = app.K_inmMSlider.Value;
            K_out = app.K_outmMSlider.Value;
            T = app.TemperatureCSlider.Value;
            Vm = ((app.R*T)/(app.z*app.F)*log(K_out/K_in))*1000; % Nernst Equation
            app.MembranePotentialmVGauge.Value = Vm;
            app.MembranePotentialmVEditField.Value = Vm;
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: K_inmMSlider
        function K_inmMSliderValueChanged(app, event)
            K_in = app.K_inmMSlider.Value;
            app.K_inmMEditField.Value = K_in;
            Vm_calculator(app);
        end

        % Value changed function: K_inmMEditField
        function K_inmMEditFieldValueChanged(app, event)
            K_in = app.K_inmMEditField.Value;
            app.K_inmMSlider.Value = K_in;
            Vm_calculator(app);
        end

        % Value changed function: K_outmMSlider
        function K_outmMSliderValueChanged(app, event)
            K_out = app.K_outmMSlider.Value;
            app.K_outmMEditField.Value = K_out;
            Vm_calculator(app);
        end

        % Value changed function: K_outmMEditField
        function K_outmMEditFieldValueChanged(app, event)
            K_out = app.K_outmMEditField.Value;
            app.K_outmMSlider.Value = K_out;
            Vm_calculator(app);
        end

        % Value changed function: TemperatureCSlider
        function TemperatureCSliderValueChanged(app, event)
            T = app.TemperatureCSlider.Value;
            app.TemperatureCEditField.Value = T;
            Vm_calculator(app);
        end

        % Value changed function: TemperatureCEditField
        function TemperatureCEditFieldValueChanged(app, event)
            T = app.TemperatureCEditField.Value;
            app.TemperatureCSlider.Value = T;
            Vm_calculator(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.K_inmMEditFieldLabel = uilabel(app.UIFigure);
            app.K_inmMEditFieldLabel.Text = 'K_in (mM)';
            app.K_inmMEditField = uieditfield(app.UIFigure, 'numeric');
            app.K_inmMEditField.ValueDisplayFormat = '%5.1f';
            app.K_inmMEditField.ValueChangedFcn = createCallbackFcn(app, @K_inmMEditFieldValueChanged, true);
            app.K_inmMSliderLabel = uilabel(app.UIFigure);
            app.K_inmMSliderLabel.Text = 'K_in (mM)';
            app.K_inmMSlider = uislider(app.UIFigure);
            app.K_inmMSlider.Limits = [0 300];
            app.K_inmMSlider.ValueChangedFcn = createCallbackFcn(app, @K_inmMSliderValueChanged, true);
            app.K_inmMSlider.Value = 150;
            app.K_outmMSliderLabel = uilabel(app.UIFigure);
            app.K_outmMSliderLabel.Text = 'K_out (mM)';
            app.K_outmMSlider = uislider(app.UIFigure);
            app.K_outmMSlider.Limits = [0 300];
            app.K_outmMSlider.ValueChangedFcn = createCallbackFcn(app, @K_outmMSliderValueChanged, true);
            app.K_outmMSlider.Value = 150;
            app.K_outmMEditFieldLabel = uilabel(app.UIFigure);
            app.K_outmMEditFieldLabel.Text = 'K_out (mM)';
            app.K_outmMEditField = uieditfield(app.UIFigure, 'numeric');
            app.K_outmMEditField.ValueDisplayFormat = '%5.1f';
            app.K_outmMEditField.ValueChangedFcn = createCallbackFcn(app, @K_outmMEditFieldValueChanged, true);
            app.TemperatureCSliderLabel = uilabel(app.UIFigure);
            app.TemperatureCSliderLabel.Text = 'Temperature (C)';
            app.TemperatureCSlider = uislider(app.UIFigure);
            app.TemperatureCSlider.ValueChangedFcn = createCallbackFcn(app, @TemperatureCSliderValueChanged, true);
            app.TemperatureCSlider.Value = 50;
            app.TemperatureCEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (C)';
            app.TemperatureCEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureCEditField.ValueDisplayFormat = '%5.1f';
            app.TemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureCEditFieldValueChanged, true);
            app.MembranePotentialmVGaugeLabel = uilabel(app.UIFigure);
            app.MembranePotentialmVGaugeLabel.Text = 'Membrane Potential (mV)';
            app.MembranePotentialmVGauge = uigauge(app.UIFigure, 'linear');
            app.MembranePotentialmVGauge.Limits = [-150 150];
            app.MembranePotentialmVEditFieldLabel = uilabel(app.UIFigure);
            app.MembranePotentialmVEditFieldLabel.Text = 'Membrane Potential (mV)';
            app.MembranePotentialmVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MembranePotentialmVEditField.ValueDisplayFormat = '%5.1f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BME301_Nernst

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end